classdef SG__app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        GridLayout                   matlab.ui.container.GridLayout
        LeftPanel                    matlab.ui.container.Panel
        TabGroup                     matlab.ui.container.TabGroup
        SetupTab                     matlab.ui.container.Tab
        SwitchMicsButtonGroup        matlab.ui.container.ButtonGroup
        NominalButton                matlab.ui.control.ToggleButton
        Button_3                     matlab.ui.control.ToggleButton
        Button_2                     matlab.ui.control.ToggleButton
        Button                       matlab.ui.control.ToggleButton
        MeasureButton                matlab.ui.control.Button
        ComputeCorrectionTFButton    matlab.ui.control.Button
        UploadButton                 matlab.ui.control.Button
        SmatrixTab                   matlab.ui.container.Tab
        SourceOrderPanel             matlab.ui.container.Panel
        DropDown                     matlab.ui.control.DropDown
        FrequencyHzEditField         matlab.ui.control.NumericEditField
        FrequencyHzEditField_2Label  matlab.ui.control.Label
        SourceTypeButtonGroup        matlab.ui.container.ButtonGroup
        SineButton                   matlab.ui.control.ToggleButton
        PulseButton                  matlab.ui.control.ToggleButton
        WhiteButton                  matlab.ui.control.ToggleButton
        MeasureButton_2              matlab.ui.control.Button
        SysLogTab                    matlab.ui.container.Tab
        RebootButton                 slrealtime.ui.control.RebootButton
        SystemLog                    slrealtime.ui.control.SystemLog
        RightPanel                   matlab.ui.container.Panel
        GridLayout2                  matlab.ui.container.GridLayout
        EditField                    matlab.ui.control.EditField
        Lamp                         matlab.ui.control.Lamp
        UIAxes                       matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            addpath('__fun');
            p = param_struct(); % is a structure with all of the parameters
            tg = slrealtime(p.tg_model);
            %% PLOT FIRST CURVE
            cla(app.UIAxes);
            try
                load('C:/Speedgoat/temp/cal.mat');
                plot(app.UIAxes,cal.freq,cal.C12);
            catch 
                pause(0.01);
            end
            %% CHECK IF CONNECTED
            try
                   app.Lamp.Enable = 'off';
                   tg.connect;
                   pause(0.01);
                   tg.isConnected == 1; %#ok<EQEFF> 
                   pause(0.01);
                   app.EditField.Value = "Speedgoat connected."; 
                   pause(0.01);
                   app.Lamp.Enable = 'on';
                   pause(0.01);
            catch 
                   app.EditField.Value = "Speedgoat disconnected.";
                   pause(0.01)
            end
        end

        % Button pushed function: MeasureButton
        function MeasureButtonPushed(app, event)
            %% DEACTIVATE BUTTONS
            set(app.MeasureButton,'Enable','off')
            set(app.ComputeCorrectionTFButton,'Enable','off')        
            %% SET PARAMETERS
            addpath('__fun');
            p = param_struct(); % is a structure with all of the parameter
            p.src_select_ab = 1; %A
            p.src_select_type = 1;%white
            p.A = 10;%set amplitude to max
            p.avg_num_wind = 30; %The number of windows with 0% overlap (x2-1 for 50% overlap).RMK: 30 FOR CAL 
            p.freq_res = 0.5; %freq_max/N_lines; %frequency resolution Hz (0.5 for s-matrix and 5 for stplot)
            p.tmax = p.avg_num_wind/p.freq_res; 
            p.i2u = 0; % bypass impedance synthesis
            %% MAKE TEMP DIRECTORY ON LOCAL DRIVE TO STORE RAW DATA
            if isfolder('C:/Speedgoat/temp') == 0
                mkdir C:/Speedgoat/temp %%% saves temp data on the local drive (for speed)
                cal = struct;
                save( 'C:/Speedgoat/temp/cal.mat','cal');
            elseif ~isfile('C:/Speedgoat/temp/cal.mat') 
                cal = struct;
                save( 'C:/Speedgoat/temp/cal.mat','cal');
            end
            %% MEASURE
            dlg = uiprogressdlg(app.UIFigure, 'Indeterminate','on','Cancelable','on');
            dlg.Message = 'Measurement in progress...';
            signal_measure_raw = SG__measure(p,dlg); %yields a timetable
            signal_measure_raw = signal_measure_raw.Variables; %isolates the variables
            %% PROCESS DATA
            dlg.Message = 'Computing Coherence...';
            if app.Button.Value
                %%% COMPUTE COHERENCE FUNCTION
                load( 'C:/Speedgoat/temp/cal.mat','cal');
                %cal.C12 = [];
                tic;
                [cal.C12,cal.freq] = mscohere(signal_measure_raw(:,2),signal_measure_raw(:,1),p.wind,[],[],p.fs_log);
                delta_t = toc; %%% delta_t is displayed at end
                %%% SAVE DATA
                app.EditField.Value = "Saving data to file..."; pause(0.01);
                cal.D12 = signal_measure_raw; %[raw_data_temp(:,1),raw_data_temp(:,2)];
                save( 'C:/Speedgoat/temp/cal.mat','cal');%raw data             
                plot(app.UIAxes,cal.freq,abs(cal.C12))
            elseif app.Button_2.Value
                %%% COMPUTE COHERENCE FUNCTION
                load( 'C:/Speedgoat/temp/cal.mat','cal');
                %cal.C13 = [];
                tic;
                [cal.C13,cal.freq] = mscohere(signal_measure_raw(:,3),signal_measure_raw(:,1),p.wind,[],[],p.fs_log);
                delta_t = toc; %%% delta_t is displayed at end
                %%% SAVE DATA
                dlg.Message = 'Saving data to file...';
                cal.D13 = signal_measure_raw;
                save( 'C:/Speedgoat/temp/cal.mat','cal');%raw data        
                %%% PLOT
                plot(app.UIAxes,cal.freq,abs(cal.C13))
            elseif app.Button_3.Value 
                %%% COMPUTE COHERENCE FUNCTION
                load( 'C:/Speedgoat/temp/cal.mat','cal');
                cal.C14 = [];
                tic;
                [cal.C14,cal.freq] = mscohere(signal_measure_raw(:,4),signal_measure_raw(:,1),p.wind,[],[],p.fs_log);
                delta_t = toc; %%% delta_t is displayed at end
                %%% SAVE DATA
                dlg.Message = 'Saving data to file...';
                cal.D14 = signal_measure_raw;
                save( 'C:/Speedgoat/temp/cal.mat','cal');%raw data  
                %%% PLOT
                plot(app.UIAxes,cal.freq,abs(cal.C14))
            elseif app.NominalButton.Value 
                %%% COMPUTE COHERENCE FUNCTION
                load( 'C:/Speedgoat/temp/cal.mat','cal');
                tic
                %cal.C21 = [];
                %cal.C31 = [];
                %cal.C41 = [];
                [cal.C21,cal.freq] = mscohere(signal_measure_raw(:,1),signal_measure_raw(:,2),p.wind,[],[],p.fs_log); 
                [cal.C31,cal.freq] = mscohere(signal_measure_raw(:,1),signal_measure_raw(:,3),p.wind,[],[],p.fs_log);
                [cal.C41,cal.freq] = mscohere(signal_measure_raw(:,1),signal_measure_raw(:,4),p.wind,[],[],p.fs_log);
                delta_t = toc;
                cal.Di1 = signal_measure_raw;
                %%% SAVE DATA TO TEMP FILE
                dlg.Message = 'Saving data to file...';
                save( 'C:/Speedgoat/temp/cal.mat','cal');
                %%% PLOT
                plot(app.UIAxes,cal.freq,abs(cal.C21));
                hold(app.UIAxes,'on')
                plot(app.UIAxes,cal.freq,abs(cal.C31));
                plot(app.UIAxes,cal.freq,abs(cal.C41));
                hold(app.UIAxes,'off');
            end
            %% END
            dlg.close() 
            app.EditField.Value = strcat("Computation time = ",string(delta_t )," s"); pause(0.01);
            %%% BUTTON ON
            set(app.MeasureButton,'Enable','on')
            set(app.ComputeCorrectionTFButton,'Enable','on')
        end

        % Button pushed function: UploadButton
        function UploadButtonPushed(app, event)
            addpath('__fun');
            p = param_struct(); % is a structure with all of the parameter
            tg = slrealtime(p.tg_model);
            try
                dlg = uiprogressdlg(app.UIFigure, 'Indeterminate','on');
                dlg.Message = 'Connecting...';
                pause(0.01);
                tg.connect;
                app.Lamp.Enable = 'off';
                pause(0.01);
                set(app.UploadButton,'Enable','off');
                pause(0.01);
                dlg.Message = 'Connection estabished.';
                pause(0.01);
                dlg.Message = 'Building target...';
                pause(0.01);
                SG__build()
                pause(0.01);
                app.EditField.Value = "System ready."; 
                app.Lamp.Enable = 'on';
                pause(0.01);
            catch
                app.Lamp.Enable = 'off';
                app.EditField.Value = "Connection failed."; 
            end
            dlg.close()
            set(app.UploadButton,'Enable','on'); %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        end

        % Button pushed function: ComputeCorrectionTFButton
        function ComputeCorrectionTFButtonPushed(app, event)
            set(app.ComputeCorrectionTFButton,'Enable','off') 
            addpath('__fun');
            p = param_struct(); % is a structure with all of the parameter
            %%% MAKE STRUCTURE TO BE SAVED IN LOCAL DIRECTORY ON SERVER
            if isfolder('./__data') == 0
                mkdir './__data' %%% saves temp data on the local directory
                cal = struct;
            elseif isfile('./__data/cal.mat') == 0
                cal = struct;
            end
            %%% INPUT CALIBRATION DATA 
            try
                dlg = uiprogressdlg(app.UIFigure, 'Indeterminate','on', 'Title', 'Correction transfer function.')
                dlg.Message = 'Loading data...';
                load('C:/Speedgoat/temp/cal.mat');                  
                %%% TRANSFER FUNCTION CORRECTION FUNCTION
                dlg.Message = 'Computing transfer functions...';
                tic;
                [cal.H12,cal.freq] = tfestimate(cal.D12(:,1),cal.D12(:,2),p.wind,[],[],p.fs_log); 
                [cal.H13,cal.freq] = tfestimate(cal.D13(:,1),cal.D13(:,3),p.wind,[],[],p.fs_log);
                [cal.H14,cal.freq] = tfestimate(cal.D14(:,1),cal.D14(:,4),p.wind,[],[],p.fs_log);
                [cal.H21,cal.freq] = tfestimate(cal.Di1(:,1),cal.Di1(:,2),p.wind,[],[],p.fs_log);%%%the subscript "a" is added here to such that the data can be directly processed in the Smatrix tab
                [cal.H31,cal.freq] = tfestimate(cal.Di1(:,1),cal.Di1(:,3),p.wind,[],[],p.fs_log);
                [cal.H41,cal.freq] = tfestimate(cal.Di1(:,1),cal.Di1(:,4),p.wind,[],[],p.fs_log); 
                %%% CORRECTION TRANSFERFUNCTION cor
                cal.H21_corr = sqrt(cal.H12.*cal.H21); %%%20221110--> changes from ./" to ".*" !!!! %correction
                cal.H31_corr = sqrt(cal.H13.*cal.H31);
                cal.H41_corr = sqrt(cal.H14.*cal.H41);
                %%%SAVE TRANSFER FUNCTIONS AND CORRECTION OR NOMINAL SWEEP IN LOCAL FOLDER
                %%% FOR IMMEDIATE POST PROCESSING
                dlg.Message = 'Saving data...';
                processed_data_a.H21 = cal.H21;
                processed_data_a.H21 = cal.H21;
                processed_data_a.H31 = cal.H31;
                processed_data_a.H41 = cal.H41;
                processed_data_a.C21 = cal.C21;
                processed_data_a.C31 = cal.C31;
                processed_data_a.C41 = cal.C41;
                processed_data_a.freq = cal.freq;

                save( sprintf('./__data/cal__%0.0f_%0.0f_%0.0fus.mat',p.freq_ini,p.freq_fin,p.ts_log/1e-6),'cal'); 
                save( 'C:/Speedgoat/temp/processed_data_a.mat','processed_data_a');
                delta_t = toc; %%% delta_t is displayed at end
                pause(0.5);
                app.EditField.Value = strcat("Computation time = ",string(delta_t )," s"); pause(0.01);
            catch
                app.EditField.Value = " "; 
                f = errordlg('Data file(s) not found.','File Error');
            end
            set(app.ComputeCorrectionTFButton,'Enable','on')  ; 
            dlg.close();
        end

        % Value changed function: EditField
        function EditFieldValueChanged(app, event)
            value = app.EditField.Value;    
        end

        % Selection changed function: SwitchMicsButtonGroup
        function TranferFunctionButtonGroupSelectionChanged(app, event)
            %%% PLOT
            addpath('__fun');
            p = param_struct(); % is a structure with all of the parameter
            try 
                cla(app.UIAxes);
                load('C:/Speedgoat/temp/cal.mat')
                if app.Button.Value
                    plot(app.UIAxes,cal.freq,cal.C12);
                elseif app.Button_2.Value
                    plot(app.UIAxes,cal.freq,cal.C13);
                elseif app.Button_3.Value
                    plot(app.UIAxes,cal.freq,cal.C14);
                elseif app.NominalButton.Value
                    plot(app.UIAxes,cal.freq,cal.C21);
                    hold(app.UIAxes,'on')
                    plot(app.UIAxes,cal.freq,cal.C31);
                    plot(app.UIAxes,cal.freq,cal.C41);
                    hold(app.UIAxes,'off');
                end
                app.EditField.Value = "";
            catch
                app.EditField.Value = "Calibration file(s) not found.";
            end 
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {488, 488};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {253, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end

        % Callback function
        function ABORTButtonPushed(app, event)
            addpath('__fun');
            p = param_struct(); % is a structure with all of the parameter
            tg = slrealtime(p.tg_model);
            tg.stop;% stops target
            pause(0.01);
            app.EditField.Value =  "Measurement aborted."; %%% VERBOSE
            pause(0.01);
            %%% BUTTON ON
            set(app.UploadButton,'Enable','on')
            set(app.MeasureButton,'Enable','on')
            set(app.MeasureButton_2,'Enable','on')
            set(app.ComputeCorrectionTFButton,'Enable','on')
        end

        % Callback function
        function SwitchValueChanged(app, event)
           
        end

        % Callback function
        function SourceButtonGroupSelectionChanged(app, event)
            addpath('__fun');
            p = param_struct(); % is a structure with all of the parameter
            try 
                pause(0.01)
                cla(app.UIAxes);
                if app.AButton.Value
                    load('C:/Speedgoat/temp/processed_data_a.mat');
                    plot(app.UIAxes,processed_data_a.freq,abs(processed_data_a.C21));
                    hold(app.UIAxes,'on')
                    plot(app.UIAxes,processed_data_a.freq,abs(processed_data_a.C31));
                    plot(app.UIAxes,processed_data_a.freq,abs(processed_data_a.C41));
                    hold(app.UIAxes,'off');
                elseif app.BButton.Value
                    load('C:/Speedgoat/temp/processed_data_b.mat');
                    plot(app.UIAxes,processed_data_b.freq,abs(processed_data_b.C21));
                    hold(app.UIAxes,'on')
                    plot(app.UIAxes,processed_data_b.freq,abs(processed_data_b.C31));
                    plot(app.UIAxes,processed_data_b.freq,abs(processed_data_b.C41));
                    hold(app.UIAxes,'off');     
                end
                app.EditField.Value = ""
            catch
                app.EditField.Value = "Data file(s) not found."
            end
        end

        % Button pushed function: MeasureButton_2
        function MeasureButton_2Pushed(app, event)
            src_type = app.SourceTypeButtonGroup.SelectedObject; % 'white constant
            src_order = app.DropDown.Value; % {'A'}    {'B'}    {'A → B'}    {'A + B'}
            try
                if isfolder('__data') == 0
                    mkdir __data;
                end        
                addpath('__fun');
                p = param_struct(); % is a structure with all of the parameter  
                p.freq_sine = app.FrequencyHzEditField.Value; % change the frequency value
            
                %%% SOURCE TYPE
                    if src_type == app.WhiteButton
                        p.src_select_type = 1; % for scattering matrix, use this
                    elseif src_type == app.PulseButton
                        p.src_select_type = 2;
                    elseif src_type == app.SineButton
                        p.src_select_type = 3;
                    end

                %%% ONLY A SELECT
                if src_order == "A" %app.AButton 
                    if isfile("C:/Speedgoat/temp/processed_data_a.mat") 
                        answer = questdlg('Data files are already loaded. Would you like to overwrite them?','Warning','Yes','Cancel','Cancel');
                        switch answer % Handle response
                            case 'Yes'
                            case 'Cancel'
                                %set(app.MeasureButton_2,'Enable','on'); pause(0.01);
                                return
                        end
                    end
                    dlg = uiprogressdlg(app.UIFigure, 'Indeterminate','on','Cancelable','on');
                    dlg.Title =  'Source A';
                    dlg.Message = 'Measuring...'; %%%
                    p.src_select_ab = 1;
                    [signal_measure_raw, signal_control_raw] = SG__measure(p,dlg); %yields a timetable
                    signal_measure_raw = signal_measure_raw.Variables; %isolates the variables
                    save('C:/Speedgoat/temp/signal_control_raw_a.mat','signal_control_raw');
                    dlg.Message = 'Computing coherence and transfer function...'; %%%
                    processed_data_a = SG__data_process(signal_measure_raw); %%%%%% ERROR HERE WHEN MANUALLY CHANGING THE tmax, the window is not adjusted...
                    dlg.Message = 'Saving data...';  %%%               
                    save('C:/Speedgoat/temp/processed_data_a.mat','processed_data_a');
                    dlg.Message = 'Plotting...';%%%
                    plot(app.UIAxes,processed_data_a.freq,abs(processed_data_a.C21));
                    hold(app.UIAxes,'on')
                    plot(app.UIAxes,processed_data_a.freq,abs(processed_data_a.C31));
                    plot(app.UIAxes,processed_data_a.freq,abs(processed_data_a.C41));
                    hold(app.UIAxes,'off');
                    app.EditField.Value = "Done.";  pause(0.01);

                %%% ONLY B SELECT
                elseif src_order == "B" %app.BButton          
                    if isfile("./__data/processed_data_b.mat")
                        answer = questdlg('data files are already loaded. Would you like to overwrite them?','Warning','Yes','Cancel','Cancel');
                        % Handle response
                        switch answer % Handle response
                            case 'Yes'
                            case 'Cancel'
                                %set(app.MeasureButton_2,'Enable','on'); pause(0.01);
                                return
                        end
                    end
                    dlg = uiprogressdlg(app.UIFigure, 'Indeterminate','on','Cancelable','on');
                    dlg.Title =  'Source B';
                    dlg.Message = 'Measuring...';%%%
                    p.src_select_ab = 2; 
                    [signal_measure_raw, signal_control_raw] = SG__measure(p,dlg);%yields a timetable
                    signal_measure_raw = signal_measure_raw.Variables; %isolates the variables
                    save('C:/Speedgoat/temp/signal_control_raw_b.mat','signal_control_raw');
                    dlg.Message = 'Computing coherence and transfer function...';%%%
                    processed_data_b = SG__data_process(signal_measure_raw); 
                    dlg.Message = 'Saving data...';%%%
                    save('C:/Speedgoat/temp/processed_data_b.mat','processed_data_b');
                    app.EditField.Value = "Done.";  pause(0.01);
                    plot(app.UIAxes,processed_data_b.freq,abs(processed_data_b.C21));
                    hold(app.UIAxes,'on')
                    plot(app.UIAxes,processed_data_b.freq,abs(processed_data_b.C31));
                    plot(app.UIAxes,processed_data_b.freq,abs(processed_data_b.C41));
                    hold(app.UIAxes,'off');

                %%% A <--> B SELECT
                elseif src_order == "A ↔ B"%app.AthenBButton 
                    if isfile("C:/Speedgoat/temp/processed_data_a.mat")&&isfile("C:/Speedgoat/temp/processed_data_b.mat")  
                        answer = questdlg('Data files are already loaded. Would you like to overwrite them?','Warning','Yes','Cancel','Cancel');
                        switch answer % Handle response
                            case 'Yes'
                            case 'Cancel'
                                %set(app.MeasureButton_2,'Enable','on'); pause(0.01);
                                return
                        end
                    end
                    %%% A
                    dlg = uiprogressdlg(app.UIFigure, 'Indeterminate','on','Cancelable','on');
                    dlg.Title =  'Source A';
                    dlg.Message = 'Measuring...';
                    p.src_select_ab = 1;
                    [signal_measure_raw, signal_control_raw]  = SG__measure(p,dlg);%yields a timetable
                    signal_measure_raw = signal_measure_raw.Variables; %isolates the variables
                    dlg.Message = 'Computing coherence and transfer function...';
                    processed_data_a = SG__data_process(signal_measure_raw); 
                    dlg.Message = 'Saving data...';
                    save('C:/Speedgoat/temp/processed_data_a.mat','processed_data_a');
                    save('C:/Speedgoat/temp/signal_control_raw_a.mat','signal_control_raw');
                    dlg.Message = 'Plotting...';
                    plot(app.UIAxes,processed_data_a.freq,abs(processed_data_a.C21));
                    hold(app.UIAxes,'on')
                    plot(app.UIAxes,processed_data_a.freq,abs(processed_data_a.C31));
                    plot(app.UIAxes,processed_data_a.freq,abs(processed_data_a.C41));
                    hold(app.UIAxes,'off');
                    app.EditField.Value = "Done.";  pause(0.01);
                    %%% B
                    dlg = uiprogressdlg(app.UIFigure, 'Indeterminate','on','Cancelable','on');
                    dlg.Title =  'Source B';
                    dlg.Message = 'Measuring...';
                    p.src_select_ab = 2; 
                    [signal_measure_raw, signal_control_raw]  = SG__measure(p,dlg);%yields a timetableapp.ABButton_2
                    signal_measure_raw = signal_measure_raw.Variables; %isolates the variables
                    dlg.Message = 'Computing coherence and transfer function...';
                    processed_data_b = SG__data_process(signal_measure_raw); 
                    dlg.Message = 'Saving data...';
                    save('C:/Speedgoat/temp/processed_data_b.mat','processed_data_b');
                    save('C:/Speedgoat/temp/signal_control_raw_b.mat','signal_control_raw');
                    app.EditField.Value = "Done.";  pause(0.01);
                    plot(app.UIAxes,processed_data_b.freq,abs(processed_data_b.C21));
                    hold(app.UIAxes,'on')
                    plot(app.UIAxes,processed_data_b.freq,abs(processed_data_b.C31));
                    plot(app.UIAxes,processed_data_b.freq,abs(processed_data_b.C41));
                    hold(app.UIAxes,'off');

                %%% A + B SELECT
                elseif src_order == "A + B" %app.BButton  %%% UPDATE THIS        
                    if isfile("./__data/processed_data_a_b.mat")
                        answer = questdlg('data files are already loaded. Would you like to overwrite them?','Warning','Yes','Cancel','Cancel');
                        % Handle response
                        switch answer % Handle response
                            case 'Yes'
                            case 'Cancel'
                                %set(app.MeasureButton_2,'Enable','on'); pause(0.01);
                                return
                        end
                    end
                    dlg = uiprogressdlg(app.UIFigure, 'Indeterminate','on','Cancelable','on');
                    dlg.Title =  'Source A + B';
                    dlg.Message = 'Measuring...';%%%
                    p.src_select_ab = 3; 
                    [signal_measure_raw, signal_control_raw] = SG__measure(p,dlg);%yields a timetable
                    %signal_measure_raw = signal_measure_raw.Variables; %isolates the variables
                    %dlg.Message = 'Computing coherence and transfer function...';%%%
                    %processed_data_a_b = SG__data_process(signal_measure_raw); 
                    dlg.Message = 'Saving data...';%%%
                    %save('C:/Speedgoat/temp/processed_data_a_b.mat','processed_data_a_b');
                    save('C:/Speedgoat/temp/signal_control_raw_a_b.mat','signal_control_raw');
                    app.EditField.Value = "Done.";  pause(0.01);
                    %plot(app.UIAxes,processed_data_a_b.F,abs(processed_data_a_b.C21));
                    %hold(app.UIAxes,'on')
                    %plot(app.UIAxes,processed_data_a_b.F,abs(processed_data_a_b.C31));
                    %plot(app.UIAxes,processed_data_a_b.F,abs(processed_data_a_b.C41));
                    %hold(app.UIAxes,'off');
                end
            end
        %dlg.close()
        end

        % Callback function
        function TransferFunction3ButtonGroupSelectionChanged(app, event)
            selectedButton = app.SwitchMicsButtonGroup.SelectedObject;       
        end

        % Size changed function: SwitchMicsButtonGroup
        function SwitchMicsButtonGroupSizeChanged(app, event)
            position = app.SwitchMicsButtonGroup.Position;
        end

        % Value changed function: DropDown
        function DropDownValueChanged(app, event)
            value = app.DropDown.Value;
            
        end

        % Drop down opening function: DropDown
        function DropDownOpening(app, event)
            
        end

        % Selection changed function: SourceTypeButtonGroup
        function SourceTypeButtonGroupSelectionChanged2(app, event)
            selectedButton = app.SourceTypeButtonGroup.SelectedObject;
            if selectedButton == app.WhiteButton
                set(app.FrequencyHzEditField,'Enable','off')
            else
                set(app.FrequencyHzEditField,'Enable','on')
            end            
        end

        % Value changed function: FrequencyHzEditField
        function FrequencyHzEditFieldValueChanged(app, event)
  
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.TabGroup = uitabgroup(app.LeftPanel);
            app.SetupTab = uitab(app.TabGroup);
            app.SetupTab.Title = 'Setup';
            app.UploadButton = uibutton(app.SetupTab, 'push');
            app.UploadButton.ButtonPushedFcn = createCallbackFcn(app, @UploadButtonPushed, true);
            app.UploadButton.Text = 'Upload';
            app.ComputeCorrectionTFButton = uibutton(app.SetupTab, 'push');
            app.ComputeCorrectionTFButton.ButtonPushedFcn = createCallbackFcn(app, @ComputeCorrectionTFButtonPushed, true);
            app.ComputeCorrectionTFButton.Text = 'Compute Correction TF';
            app.MeasureButton = uibutton(app.SetupTab, 'push');
            app.MeasureButton.ButtonPushedFcn = createCallbackFcn(app, @MeasureButtonPushed, true);
            app.MeasureButton.Text = 'Measure';
            app.SwitchMicsButtonGroup = uibuttongroup(app.SetupTab);
            app.SwitchMicsButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @TranferFunctionButtonGroupSelectionChanged, true);
            app.SwitchMicsButtonGroup.Title = 'Switch Mics.';
            app.SwitchMicsButtonGroup.SizeChangedFcn = createCallbackFcn(app, @SwitchMicsButtonGroupSizeChanged, true);
            app.Button = uitogglebutton(app.SwitchMicsButtonGroup);
            app.Button.Text = '1 & 2';
            app.Button.Value = true;
            app.Button_2 = uitogglebutton(app.SwitchMicsButtonGroup);
            app.Button_2.Text = '1 & 3';
            app.Button_3 = uitogglebutton(app.SwitchMicsButtonGroup);
            app.Button_3.Text = '1 & 4';
            app.NominalButton = uitogglebutton(app.SwitchMicsButtonGroup);
            app.NominalButton.Text = 'Nominal';
            app.SmatrixTab = uitab(app.TabGroup);
            app.SmatrixTab.Title = 'S-matrix';
            app.MeasureButton_2 = uibutton(app.SmatrixTab, 'push');
            app.MeasureButton_2.ButtonPushedFcn = createCallbackFcn(app, @MeasureButton_2Pushed, true);
            app.MeasureButton_2.Text = 'Measure';
            app.SourceTypeButtonGroup = uibuttongroup(app.SmatrixTab);
            app.SourceTypeButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @SourceTypeButtonGroupSelectionChanged2, true);
            app.SourceTypeButtonGroup.Title = 'Source Type';
            app.WhiteButton = uitogglebutton(app.SourceTypeButtonGroup);
            app.WhiteButton.Text = 'White';
            app.WhiteButton.Value = true;
            app.PulseButton = uitogglebutton(app.SourceTypeButtonGroup);
            app.PulseButton.Text = 'Pulse';
            app.SineButton = uitogglebutton(app.SourceTypeButtonGroup);
            app.SineButton.Text = 'Sine';
            app.FrequencyHzEditField_2Label = uilabel(app.SmatrixTab);
            app.FrequencyHzEditField_2Label.Enable = 'off';
            app.FrequencyHzEditField_2Label.Text = 'Frequency (Hz)';
            app.FrequencyHzEditField = uieditfield(app.SmatrixTab, 'numeric');
            app.FrequencyHzEditField.Limits = [0 40000];
            app.FrequencyHzEditField.ValueChangedFcn = createCallbackFcn(app, @FrequencyHzEditFieldValueChanged, true);
            app.FrequencyHzEditField.Enable = 'off';
            app.FrequencyHzEditField.Value = 635;
            app.SourceOrderPanel = uipanel(app.SmatrixTab);
            app.SourceOrderPanel.Title = 'Source Order';
            app.DropDown = uidropdown(app.SourceOrderPanel);
            app.DropDown.Items = {'A', 'B', 'A ↔ B', 'A + B'};
            app.DropDown.DropDownOpeningFcn = createCallbackFcn(app, @DropDownOpening, true);
            app.DropDown.ValueChangedFcn = createCallbackFcn(app, @DropDownValueChanged, true);
            app.DropDown.Value = 'A';
            app.SysLogTab = uitab(app.TabGroup);
            app.SysLogTab.Title = 'SysLog';
            app.SystemLog = slrealtime.ui.control.SystemLog(app.SysLogTab);
            app.RebootButton = slrealtime.ui.control.RebootButton(app.SysLogTab);
            app.RightPanel = uipanel(app.GridLayout);
            app.GridLayout2 = uigridlayout(app.RightPanel);
            app.UIAxes = uiaxes(app.GridLayout2);
            title(app.UIAxes, 'Correlation w/r to mic. 1')
            xlabel(app.UIAxes, 'Freq (Hz)')
            ylabel(app.UIAxes, 'a.u.')
            app.UIAxes.YLim = [0 1];
            app.Lamp = uilamp(app.GridLayout2);
            app.EditField = uieditfield(app.GridLayout2, 'text');
            app.EditField.ValueChangedFcn = createCallbackFcn(app, @EditFieldValueChanged, true);
            app.EditField.Editable = 'off';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SG__app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end